classdef Test_fallsensor_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        KontrollpanelPanel           matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        SerieportTab                 matlab.ui.container.Tab
        OpneButton                   matlab.ui.control.Button
        COMportDropDownLabel         matlab.ui.control.Label
        COMportDropDown              matlab.ui.control.DropDown
        BaudrateDropDownLabel        matlab.ui.control.Label
        BaudrateDropDown             matlab.ui.control.DropDown
        Lamp                         matlab.ui.control.Lamp
        LukkButton                   matlab.ui.control.Button
        FilplasseringTab             matlab.ui.container.Tab
        VelgmappefilerskallagrastiButton  matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        ParameterTab                 matlab.ui.container.Tab
        FallhgdecmEditFieldLabel     matlab.ui.control.Label
        FallhgdecmEditField          matlab.ui.control.NumericEditField
        TypedempingEditFieldLabel    matlab.ui.control.Label
        TypedempingEditField         matlab.ui.control.EditField
        StegifalletEditFieldLabel    matlab.ui.control.Label
        StegifalletEditField         matlab.ui.control.NumericEditField
        TjukkelsedempingcmEditFieldLabel  matlab.ui.control.Label
        TjukkelsedempingcmEditField  matlab.ui.control.NumericEditField
        Image                        matlab.ui.control.Image
        StarttestButton              matlab.ui.control.Button
        Lamp_2                       matlab.ui.control.Lamp
        ListebehandlingTab           matlab.ui.container.Tab
        LeggtilButton                matlab.ui.control.Button
        FjerneButton                 matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        LoggLabel                    matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        ListeTab                     matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        GraferTab                    matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serieport % Serieporten som brukast for å lese data frå Arduino
        konsolltekst
        filsti
        fallparameter
        falldata
        % Legg til liste for falldata av datatypen Falldata (eigendef.
        % klasse)
        listeFalldata
        UITable_valgtIndeks
        plotHandle_a
        plotHandle_af
        plotHandle_h
        
    end
    
    methods (Access = private)
        
        function nyttFall = LoggData(app)
            s = app.serieport;
            import('Klasser.Falldata')
            if(~strcmp(string(app.filsti),'0') && ~isempty(s))
                app.KontrollpanelPanel.Enable = 'off';
                app.Lamp_2.Color = 'green';
                
                nyttFall = Falldata();
                nyttFall.Fallhogde = app.FallhgdecmEditField.Value;
                nyttFall.Steg = app.StegifalletEditField.Value;
                nyttFall.Type_demping= app.TypedempingEditField.Value;
                nyttFall.Tjukkelse_demping = app.TjukkelsedempingcmEditField.Value;
                
                pause(5)
                while(s.NumBytesAvailable > 0)
                    verdi = { readline(s) };
                    app.konsolltekst = [app.konsolltekst string(verdi)];
                end
                app.ListBox.Items = app.konsolltekst;
                
                rawut=[];
                cnt=0;
                flag=1;
                pause(1)
                
                %write(s, "START", "string");
                writeline(s,'START')
                app.konsolltekst = [app.konsolltekst "Starter avlesing..."];
                app.ListBox.Items = app.konsolltekst;
                
                % Må finne ut kor lang tid det tar totalt å logge, samt når knappen går lav
                
                while flag==1
                    raw=readline(s);
                    if(cnt == 0)
                        %tid_startLogg = datetime('now');
                        tic_knappEndra = tic;
                        cnt = 1;
                    end
                    if (~isempty(rawut) && cnt<2 && ~all(rawut(:,5)))
                        %tid_knappEndra = datetime('now');
                        toc_knappEndra = toc(tic_knappEndra);
                        tic_stopp = tic;
                        cnt = 2;
                    end
                    flag = ~contains(raw,'Avslutter');
                    
                    try
                        
                        raw2=str2num(raw);
                        rawut=[rawut;raw2];
                    catch
                        app.konsolltekst = [app.konsolltekst "Problem med konvertering fra tekst til tall"];
                        app.ListBox.Items = app.konsolltekst;
                        %disp('Problem med konvertering fra tekst til tall')
                    end
                    
                    %time_raw=now;
                end
                toc_stopp = toc(tic_stopp);
                tid_slutt=datetime('now');
                % tider = [tid_knappEndra tid_slutt];
                % dt = diff(tider);
                
                tid_slutt=datestr(tid_slutt,'yyyy_mm_dd_HH_MM_SS');
                nyttFall.Filnavn = strcat('data_', tid_slutt);
                nyttFall.Data = rawut;
                nyttFall = nyttFall.LoggingFerdig();
                
                % Lagre .csv
                fil=strcat(app.filsti, '\data_', tid_slutt,'.csv');
                dlmwrite(fil,rawut,'precision',8);
                
                %                 % Lagre .mat
                %                 fil2 = strcat(app.filsti, '\data_', tid_slutt);
                %                 save(fil2, 'nyttFall'); % standard format er .mat
                %                 disp(strcat('data_', tid_slutt, ' er lagra til fil'))
                %skriv til fil
                %dlmwrite('temp1.txt',[time_raw,temp_raw],'-append','precision',12)
                %end
                
                % TODO: Må vurdere om denne er på eit greitt format. Den
                % gir feil i overgang frå knappetrykk
                tidsintervall = (toc_knappEndra + toc_stopp) / length(rawut);
                tidVec_forKnapp = toc_knappEndra:-tidsintervall:0;
                tidVec_forStopp = 0:tidsintervall:toc_stopp;
                
                % Tidsvektoren må vere like stor som antall målinger, kutter sluttida då
                % den ikkje er kritisk
                while(length(tidVec_forKnapp) + length(tidVec_forStopp) > length(rawut))
                    tidVec_forStopp = tidVec_forStopp(1:end-1);
                end
                tidVec = [-tidVec_forKnapp tidVec_forStopp];
                nyttFall.Data(:,6) = tidVec;
                
                % Lagre .mat
                fil2 = strcat(app.filsti, '\data_', tid_slutt);
                save(fil2, 'nyttFall'); % standard format er .mat
                disp(strcat('data_', tid_slutt, ' er lagra til fil'))
                
                app.listeFalldata = [app.listeFalldata nyttFall]
                %VurderData(app, nyttFall, tid_slutt);
                % Vurder data
                % Plotting av data
                app.Lamp_2.Color = '#d3d3d3';
                app.KontrollpanelPanel.Enable = 'on';
                
            elseif (strcmp(string(app.filsti),'0') && ~isempty(s))
                % Velg filplassering
                nyttFall = [];
                nyTekst = {'Sjekk at du har valgt ei filplassering.'};
                app.konsolltekst = [app.konsolltekst nyTekst];
                app.ListBox.Items = app.konsolltekst;
                
            elseif (~strcmp(string(app.filsti),'0') && isempty(s))
                % Sjekk at serieport er open
                nyttFall = [];
                nyTekst = {'Sjekk at serieporten er kopla til rett COM-port og er open.'};
                app.konsolltekst = [app.konsolltekst nyTekst];
                app.ListBox.Items = app.konsolltekst;
            else
                % Velg filplassering og opne serieport
                nyttFall = [];
                nyTekst = {'Sjekk at serieporten er kopla til rett COM-port og er open samt at du har valgt ei filplassering.'};
                app.konsolltekst = [app.konsolltekst nyTekst];
                app.ListBox.Items = app.konsolltekst;
            end
        end
        
        function fall = LastData_arr(app)
            import('Klasser.Falldata');
            [file, path] = uigetfile('.mat', 'MultiSelect', 'on', 'Velg ei datafil som skal plottast');
            
            % Sjekk om brukaren har valgt ei fil, for å unngå unntak dersom
            % ikkje
            fannFiler = false;
            if(~strcmp(string(path), '0'))
                if(ischar(file))
                    if(~strcmp(string(file), '0'))
                        fannFiler = true;
                    end
                elseif(iscell(file))
                    for i=1:length(file)
                        if(~strcmp(string(file(i)), '0'))
                            fannFiler = true;
                            break;
                        end
                    end
                end               
            end
                
            %if(~strcmp(string(file), '0') && ~strcmp(string(path), '0'))
            if(fannFiler)
                deleteEntry = []; % Tomme filer som ikkje skal plottast
                % Må sjekke om eg har lasta inn ei eller fleire filer
                % 'char' og 'cell'
                if(ischar(file)) % Ei fil
                    fall = LastData_fil(app, path, file);
                elseif(iscell(file)) % Fleire filer
                    %[fall] = Klasser.Falldata();
                    fall = {};
                    
                    for i=1:length(file)
                        fall{i} = LastData_fil(app, path, file{i});
                        if(isempty(fall{i}))
                            deleteEntry(end+1) = i;
                        end
                    end
                end
                
                if(~isempty(deleteEntry))
                    fall(deleteEntry) = [];
                end
            else
                fall = [];
            end
        end
        
        function  fall = LastData_fil(app, path, file)
            import('Klasser.Falldata');
            fall = Falldata();
            
            if(~strcmp(string(file), '0'))
                file = strtok((file), '.');
                
                if(~isempty(app.listeFalldata))
                    erLagtTil = any(strcmp(string(file),{app.listeFalldata(:).Filnavn}));
                    if(~erLagtTil)
                        fall = fall.LastData(path, file);
                        app.listeFalldata = [app.listeFalldata fall];
                        fall = LastData_obj(app, fall);
                    else
                        nyTekst = {strcat(file, ' er allereie lagt inn.')};
                        app.konsolltekst = [app.konsolltekst nyTekst];
                        app.ListBox.Items = app.konsolltekst;
                    end
                else
                    fall = fall.LastData(path, file);
                    app.listeFalldata = [app.listeFalldata fall];
                    fall = LastData_obj(app, fall);
                end
                if(isempty(fall.Data)) % Dersom fila manglar data av ein eller annan grunn. Gjer det lettare å sjekke i PlotData()
                    fall = [];
                end
            end
        end
        
        function fall = LastData_obj(app, fall)
            if (~isempty(fall))
                try
                    import('Klasser.Falldata');
                    
                    oldVals = get(app.UITable, 'Data');
                    max_acc = str2double(compose('%3.f', max(fall.Akselerasjon)));
                    newVals = {fall.Filnavn, fall.Fallhogde , fall.Steg, fall.Type_demping, fall.Tjukkelse_demping, max_acc, fall.Fall};
                    newVals = [oldVals; newVals];
                    set(app.UITable, 'Data', newVals)
                catch ME
                    nyTekst = { strcat('Feil: ', ME.message) };
                    disp(nyTekst)
                    app.konsolltekst = [app.konsolltekst nyTekst];
                    app.ListBox.Items = app.konsolltekst;
                end
            end
        end
        
        function PlotData(app, fall)
            if(~isempty(fall))
                if(iscell(fall))
                    for i=1:length(fall)
                        app.plotHandle_a{end+1} = plot(app.UIAxes,fall{i}.Akselerasjon);
                        hold(app.UIAxes, 'on')
                        app.plotHandle_af{end+1} = plot(app.UIAxes, fall{i}.Akselerasjon_filt);
                        %hold(app.UIAxes, 'off')
                        
                        app.plotHandle_h{end+1} = plot(app.UIAxes2,fall{i}.Hogde);
                        hold(app.UIAxes2, 'on')
                    end
                else
                    app.plotHandle_a{end+1} = plot(app.UIAxes,fall.Akselerasjon);
                    hold(app.UIAxes, 'on')
                    app.plotHandle_af{end+1} = plot(app.UIAxes, fall.Akselerasjon_filt);
                    %hold(app.UIAxes, 'off')
                    
                    app.plotHandle_h{end+1} = plot(app.UIAxes2,fall.Hogde);
                    hold(app.UIAxes2, 'on')
                end               
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Legg til eit utvalg av baudrater i DropDown ved oppstart
            baudrater = {300, 600, 1200, 2400, 4800, 9600, 14400, 19200, 28800, 38400, 57600, 115200, 230400, 250000, 256000, 500000 };
            baudrater = string(baudrater);
            app.BaudrateDropDown.Items = baudrater;
            
            % Velg den første serieporten i DropDown dersom nokon er tilgjengelig
            serieporter = serialportlist("available");
            if(~isempty(serieporter))
                app.COMportDropDown.Items = serieporter(1);
            end
            
            % Lampa som indikerer at serieporten er tilkopla er av ved oppstart
            app.Lamp.Color = '#d3d3d3';
            app.Lamp_2.Color = '#d3d3d3';
            
            % Setter mine properties til standardverdier
            app.filsti = 0;
            app.FallhgdecmEditField.Value = 100;
            app.StegifalletEditField.Value = 1;
            app.TypedempingEditField.Value = "Skumgummi";
            app.TjukkelsedempingcmEditField.Value = 5;
            
            app.konsolltekst = []
            import('Klasser.Falldata');
            %app.UITable.ColumnName = {'Filnavn', 'Fallhogde', 'Steg', 'Type demping', 'Tjukkelse demping', 'Maks aks.', 'Fall'};
            
            app.plotHandle_a = {};
            
        end

        % Drop down opening function: COMportDropDown
        function COMportDropDownOpening(app, event)
            serieporter = serialportlist("available");
            app.COMportDropDown.Items = serieporter;
        end

        % Button pushed function: OpneButton
        function OpneButtonPushed(app, event)
            try
                if(isempty(app.serieport))
                    app.serieport = serialport(app.COMportDropDown.Value, str2double(app.BaudrateDropDown.Value));
                    app.Lamp.Color = 'green';
                    nyTekst = {'Serieport opna'}
                    app.konsolltekst = [app.konsolltekst nyTekst];
                    app.ListBox.Items = app.konsolltekst;
                    %pause(2);
                    %app.TabGroup.SelectedTab = app.FilplasseringTab;
                end
            catch ME
                %disp(strcat('Problem med tilkopling av serieporten.', ME.message))
                nyTekst = {strcat('Problem med konvertering fra tekst til tall', ME.message)}
                app.konsolltekst = [app.konsolltekst nyTekst];
                app.ListBox.Items = app.konsolltekst;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(exist('app.serieport', 'var'))
                app.serieport = [];
                disp('Lukker serieport')
            end
            delete(app)
        end

        % Button pushed function: LukkButton
        function LukkButtonPushed(app, event)
            if(~isempty(app.serieport))
                app.serieport = [];
                %disp('Serieport lukka.')
                app.konsolltekst = [app.konsolltekst "Serieport lukka"];
                app.ListBox.Items = app.konsolltekst;
                app.Lamp.Color = '#d3d3d3';
            end
        end

        % Button pushed function: VelgmappefilerskallagrastiButton
        function VelgmappefilerskallagrastiButtonPushed(app, event)
            filsti = uigetdir([],'Velg mappa der loggfiler skal lagrast.');
            if(filsti~=0)
                app.TextArea.Value = filsti;
                app.filsti = filsti;
            end
        end

        % Button pushed function: StarttestButton
        function StarttestButtonPushed(app, event)
            fall = LoggData(app);
            LastData_obj(app,fall);
            PlotData(app,fall);
        end

        % Button pushed function: LeggtilButton
        function LeggtilButtonPushed(app, event)
            fall = LastData_arr(app);
            PlotData(app, fall)
        end

        % Button pushed function: FjerneButton
        function FjerneButtonPushed(app, event)
            if(~isempty(app.UITable_valgtIndeks))
                indekser = app.UITable_valgtIndeks(:,1);
                
                % Må slette innlegg i UITable, begge plot (2 for aks.) samt i
                % listeFalldata datafelt. Antar at synleg indeks i UITable er
                % same indeks som i variablane
                
                for i=1:length(indekser)
                    delete(app.plotHandle_a{indekser(i)});
                    delete(app.plotHandle_af{indekser(i)});
                    delete(app.plotHandle_h{indekser(i)});
                end
                app.plotHandle_a(indekser) = [];
                app.plotHandle_af(indekser) = [];
                app.plotHandle_h(indekser) = [];
                                
                app.UITable.Data(indekser,:) = [];
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.UITable_valgtIndeks = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.KontrollpanelPanel = uipanel(app.UIFigure);
            app.KontrollpanelPanel.Title = 'Kontrollpanel';
            app.TabGroup = uitabgroup(app.KontrollpanelPanel);
            app.SerieportTab = uitab(app.TabGroup);
            app.SerieportTab.Title = 'Serieport';
            app.OpneButton = uibutton(app.SerieportTab, 'push');
            app.OpneButton.ButtonPushedFcn = createCallbackFcn(app, @OpneButtonPushed, true);
            app.OpneButton.Text = 'Opne';
            app.COMportDropDownLabel = uilabel(app.SerieportTab);
            app.COMportDropDownLabel.Text = 'COM-port';
            app.COMportDropDown = uidropdown(app.SerieportTab);
            app.COMportDropDown.Items = {};
            app.COMportDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMportDropDownOpening, true);
            app.COMportDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.SerieportTab);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.BaudrateDropDown = uidropdown(app.SerieportTab);
            app.BaudrateDropDown.Items = {};
            app.BaudrateDropDown.Value = {};
            app.Lamp = uilamp(app.SerieportTab);
            app.LukkButton = uibutton(app.SerieportTab, 'push');
            app.LukkButton.ButtonPushedFcn = createCallbackFcn(app, @LukkButtonPushed, true);
            app.LukkButton.Text = 'Lukk';
            app.FilplasseringTab = uitab(app.TabGroup);
            app.FilplasseringTab.Title = 'Filplassering';
            app.VelgmappefilerskallagrastiButton = uibutton(app.FilplasseringTab, 'push');
            app.VelgmappefilerskallagrastiButton.ButtonPushedFcn = createCallbackFcn(app, @VelgmappefilerskallagrastiButtonPushed, true);
            app.VelgmappefilerskallagrastiButton.Text = 'Velg mappe filer skal lagrast i';
            app.TextArea = uitextarea(app.FilplasseringTab);
            app.TextArea.Editable = 'off';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.FallhgdecmEditFieldLabel = uilabel(app.ParameterTab);
            app.FallhgdecmEditFieldLabel.Text = 'Fallhøgde [cm]';
            app.FallhgdecmEditField = uieditfield(app.ParameterTab, 'numeric');
            app.TypedempingEditFieldLabel = uilabel(app.ParameterTab);
            app.TypedempingEditFieldLabel.Text = 'Type demping';
            app.TypedempingEditField = uieditfield(app.ParameterTab, 'text');
            app.StegifalletEditFieldLabel = uilabel(app.ParameterTab);
            app.StegifalletEditFieldLabel.Text = 'Steg i fallet';
            app.StegifalletEditField = uieditfield(app.ParameterTab, 'numeric');
            app.TjukkelsedempingcmEditFieldLabel = uilabel(app.ParameterTab);
            app.TjukkelsedempingcmEditFieldLabel.Text = 'Tjukkelse demping [cm]';
            app.TjukkelsedempingcmEditField = uieditfield(app.ParameterTab, 'numeric');
            app.Image = uiimage(app.ParameterTab);
            app.Image.ImageSource = 'figur_fallparameter_v2.png';
            app.StarttestButton = uibutton(app.ParameterTab, 'push');
            app.StarttestButton.ButtonPushedFcn = createCallbackFcn(app, @StarttestButtonPushed, true);
            app.StarttestButton.Text = 'Start test';
            app.Lamp_2 = uilamp(app.ParameterTab);
            app.ListebehandlingTab = uitab(app.TabGroup);
            app.ListebehandlingTab.Title = 'Listebehandling';
            app.LeggtilButton = uibutton(app.ListebehandlingTab, 'push');
            app.LeggtilButton.ButtonPushedFcn = createCallbackFcn(app, @LeggtilButtonPushed, true);
            app.LeggtilButton.Text = 'Legg til';
            app.FjerneButton = uibutton(app.ListebehandlingTab, 'push');
            app.FjerneButton.ButtonPushedFcn = createCallbackFcn(app, @FjerneButtonPushed, true);
            app.FjerneButton.Text = 'Fjerne';
            app.ListBox = uilistbox(app.KontrollpanelPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.LoggLabel = uilabel(app.KontrollpanelPanel);
            app.LoggLabel.Text = 'Logg';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ListeTab = uitab(app.TabGroup2);
            app.ListeTab.Title = 'Liste';
            app.UITable = uitable(app.ListeTab);
            app.UITable.ColumnName = {'Filnavn'; 'Fallhogde'; 'Steg'; 'Type demping'; 'Tjukkelse demping'; 'Maks aks.'; 'Fall'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GraferTab = uitab(app.TabGroup2);
            app.GraferTab.Title = 'Grafer';
            app.UIAxes = uiaxes(app.GraferTab);
            title(app.UIAxes, 'Akselerasjon')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'mG')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GraferTab);
            title(app.UIAxes2, 'Trykk')
            xlabel(app.UIAxes2, 'Sample')
            ylabel(app.UIAxes2, 'Hogde')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test_fallsensor_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end